classdef diplacementAndForceAnalysisDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PointlikedisplacementandforcesUIFigure  matlab.ui.Figure
        SaveButton                    matlab.ui.control.Button
        RemovelastplotButton          matlab.ui.control.Button
        SimulationListBox             matlab.ui.control.ListBox
        TabGroup                      matlab.ui.container.TabGroup
        DisplacementTab               matlab.ui.container.Tab
        DisplacementareaTipsImage     matlab.ui.control.Image
        AreaplotPanel                 matlab.ui.container.Panel
        ContoursEditField             matlab.ui.control.EditField
        ContoursCheckBox              matlab.ui.control.CheckBox
        AreaplotButton                matlab.ui.control.Button
        DisplacementTypeButtonGroup   matlab.ui.container.ButtonGroup
        SubstrateButton               matlab.ui.control.RadioButton
        CellsButton                   matlab.ui.control.RadioButton
        DisplacementTipsImage         matlab.ui.control.Image
        Displacement1DTipsImage       matlab.ui.control.Image
        mLabel                        matlab.ui.control.Label
        MaximumdisplacementEditField  matlab.ui.control.NumericEditField
        MaximumdisplacementEditFieldLabel  matlab.ui.control.Label
        mLabel_2                      matlab.ui.control.Label
        WindowsizeEditField           matlab.ui.control.NumericEditField
        WindowsizeEditFieldLabel      matlab.ui.control.Label
        EndDropDown                   matlab.ui.control.DropDown
        EndDropDownLabel              matlab.ui.control.Label
        StartDropDown                 matlab.ui.control.DropDown
        StartDropDownLabel            matlab.ui.control.Label
        DplotButtonGroup_3            matlab.ui.container.ButtonGroup
        ColorpickerCheckBox           matlab.ui.control.CheckBox
        LegendEditField               matlab.ui.control.EditField
        LegendEditFieldLabel          matlab.ui.control.Label
        NewplotCheckBox               matlab.ui.control.CheckBox
        LogYaxisCheckBox              matlab.ui.control.CheckBox
        PlotdistributionButton        matlab.ui.control.Button
        PlotallButton                 matlab.ui.control.Button
        HorizontalCheckBox            matlab.ui.control.CheckBox
        VerticalfullButton            matlab.ui.control.RadioButton
        VerticalhalfButton            matlab.ui.control.RadioButton
        ForcesTab                     matlab.ui.container.Tab
        Forces1DTipsImage             matlab.ui.control.Image
        ForcesareaTipsImage           matlab.ui.control.Image
        AreaplotPanel_2               matlab.ui.container.Panel
        AreaplotButton_2              matlab.ui.control.Button
        ForcesTipsImage               matlab.ui.control.Image
        TypeDropDown                  matlab.ui.control.DropDown
        TypeDropDownLabel             matlab.ui.control.Label
        ForceDropDown                 matlab.ui.control.DropDown
        ForceDropDownLabel            matlab.ui.control.Label
        WindowsizeEditField_2         matlab.ui.control.NumericEditField
        WindowsizeEditField_2Label    matlab.ui.control.Label
        TimePointDropDown             matlab.ui.control.DropDown
        TimePointDropDownLabel        matlab.ui.control.Label
        mLabel_3                      matlab.ui.control.Label
        DplotButtonGroup_2            matlab.ui.container.ButtonGroup
        ColorpickerCheckBox_2         matlab.ui.control.CheckBox
        LegendEditField_2             matlab.ui.control.EditField
        LegendEditField_2Label        matlab.ui.control.Label
        NewplotCheckBox_2             matlab.ui.control.CheckBox
        LogYaxisCheckBox_2            matlab.ui.control.CheckBox
        PlotdistributionButton_2      matlab.ui.control.Button
        PlotallButton_2               matlab.ui.control.Button
        HorizontalCheckBox_2          matlab.ui.control.CheckBox
        VerticalfullButton_2          matlab.ui.control.RadioButton
        VerticalhalfButton_2          matlab.ui.control.RadioButton
        CloseButton                   matlab.ui.control.Button
    end


    properties (Access = public)
        CallingApp % Description
        analysisFigureHandle = 0;
        numberOfAnalysisPlots = 0;
        barChartData = [];
        plotHandle = {};
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)

            app.PointlikedisplacementandforcesUIFigure.Visible = 'Off';
            app.CallingApp = mainapp;

            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

            app.PointlikedisplacementandforcesUIFigure.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.PointlikedisplacementandforcesUIFigure.Position(3)/2;
            app.PointlikedisplacementandforcesUIFigure.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.PointlikedisplacementandforcesUIFigure.Position(4)/2;

            for i = 1:length(app.CallingApp.plotImport)
                if strcmp(app.CallingApp.plotImport(i).simulationType,'pointlike')
                    path = fileparts(app.CallingApp.plotImport(i).folderName);
                    fileName = app.CallingApp.plotImport(i).folderName(length(path)+2:end);
                    app.SimulationListBox.Items{end+1} = fileName;
                end
            end
            
            app.SaveButton.Icon = [app.CallingApp.defaultPath 'settings/images/save.png'];
            
            app.PointlikedisplacementandforcesUIFigure.Icon = [app.CallingApp.defaultPath 'settings/images/logo_v2.png'];
            
            app.DisplacementTipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png'];
            app.DisplacementTipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/pointlike_displacement_main.txt']);
            app.DisplacementareaTipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png'];
            app.DisplacementareaTipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/pointlike_displacement_area.txt']);
            app.Displacement1DTipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png'];
            app.Displacement1DTipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/pointlike_displacement_1d.txt']);

            app.ForcesTipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png'];
            app.ForcesTipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/pointlike_forces_main.txt']);
            app.ForcesareaTipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png'];
            app.ForcesareaTipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/pointlike_forces_area.txt']);
            app.Forces1DTipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png'];
            app.Forces1DTipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/pointlike_forces_1d.txt']);
            
            app.PointlikedisplacementandforcesUIFigure.Visible = 'On';

        end

        % Callback function: CloseButton, 
        % PointlikedisplacementandforcesUIFigure
        function UIFigureCloseRequest(app, event)
            disable_enable_all_function(app.CallingApp,'On');

            figure(app.CallingApp.EpiMechUIFigure);
            delete(app);
        end

        % Value changed function: SimulationListBox
        function SimulationListBoxValueChanged(app, event)

            updateTimes = false;
            if length(app.SimulationListBox.Value) == 1

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)

                    if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                        fileIdx = ii;
                        break
                    end

                end

                if ~isempty(app.StartDropDown.Items)

                    if length(app.StartDropDown.Items) == app.CallingApp.plotImport(fileIdx).nTimePoints
                        d.spar = app.CallingApp.plotImport(fileIdx).scaledParameters;
                        timeScalingFactor = 1/(app.CallingApp.plotImport(fileIdx).systemParameters.scalingTime*app.CallingApp.plotImport(fileIdx).exportOptions.exportDt);
                        timeString = get_time_string(d,(app.CallingApp.plotImport(fileIdx).nTimePoints-1)./timeScalingFactor,0);
                        if ~strcmp(timeString,app.StartDropDown.Items{end})
                            updateTimes = true;
                        end
                    else
                        updateTimes = true;
                    end
                else
                    updateTimes = true;

                end
                if updateTimes

                    app.StartDropDown.Items = {};
                    app.EndDropDown.Items = {};

                    app.TimePointDropDown.Items = {};

                    d.spar = app.CallingApp.plotImport(fileIdx).scaledParameters;
                    numTemp = num2cell(1:app.CallingApp.plotImport(fileIdx).nTimePoints);

                    timeScalingFactor = 1/(app.CallingApp.plotImport(fileIdx).systemParameters.scalingTime*app.CallingApp.plotImport(fileIdx).exportOptions.exportDt);

                    timeStrings = cellfun(@(i) get_time_string(d,(i-1)./timeScalingFactor,0),numTemp,'UniformOutput',false);
                    app.StartDropDown.Items = timeStrings;
                    app.EndDropDown.Items = timeStrings;
                    app.StartDropDown.Value = app.StartDropDown.Items(1);
                    app.EndDropDown.Value = app.EndDropDown.Items(end);

                    app.TimePointDropDown.Items = timeStrings;
                    app.TimePointDropDown.Value = app.TimePointDropDown.Items(end);

                end
            end

            for i = 1:length(app.SimulationListBox.Value)


                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value{i})
                            fileIdx = ii;
                            break
                        end
                    end
                end

                folderName = app.CallingApp.plotImport(fileIdx).folderName;

                app.ForceDropDown.Items = set_forces_to_dropdown(app.ForceDropDown.Items,folderName);

            end

            if ~isempty(app.SimulationListBox.Value)
                app.AreaplotButton.Enable = 'on';
                app.AreaplotButton_2.Enable = 'on';
                app.PlotallButton.Enable = 'on';
                app.PlotallButton_2.Enable = 'on';
                app.PlotdistributionButton.Enable = 'on';
                app.PlotdistributionButton_2.Enable = 'on';
            end
        end

        % Button pushed function: AreaplotButton
        function AreaplotButtonPushed(app, event)
            [xq,yq] = meshgrid(-400:1:400,-400:1:400);


            dispData = zeros(length(xq(:,1)),length(xq(1,:)),length(app.SimulationListBox.Value));

            previousSelected = app.CallingApp.selectedFile;

            for i = 1:length(app.SimulationListBox.Value)

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value{i})
                            fileIdx = ii;
                            break
                        end
                    end
                end

                app.CallingApp.selectedFile = fileIdx;

                d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
                d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
                d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;


                d.pl.substrateStyle = 1;
                d.pl.substrateForcesCentral = 0;
                d.pl.substrateForcesRepulsion = 0;
                d.pl.substrateForcesRestoration = 0;
                d.pl.substrateForcesFocalAdhesions = 0;
                d.pl.substrateForcesTotal = 0;
                d.pl.titleType = 0;
                d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
                d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


                d.simset.simulationType = 2;

                oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

                dispData = get_area_displacements(app,d,dispData,fileIdx,i,xq,yq);
                if dispData == -1
                    app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                    app.CallingApp.selectedFile = previousSelected;
                    uialert(app.PointlikedisplacementandforcesUIFigure, ['Selected simulation number ' num2str(iSimulation) ' does not have substrate data.'],'Data missing');
                    return
                end
                app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;

            end

            dispData = mean(dispData,3);

            d = setup_axis_area_plot(app,d);

            hold on
            if ~app.ContoursCheckBox.Value
                contourf(d.pl.axesHandle,xq,yq,dispData,256,'LineColor','none');
            else
                levels = textscan(app.ContoursEditField.Value,'%f');
                contourf(d.pl.axesHandle,xq,yq,dispData,levels{1},'LineColor','none');
            end

            mymap = csvread('./settings/Misc/mymap.csv');
            colormap(mymap)
            colorbar
            caxis([0, app.MaximumdisplacementEditField.Value]);
            hold off
            if app.CellsButton.Value
                title('Cell displacement')
            else
                title('Substrate displacement')
            end
            
            app.CallingApp.selectedFile = previousSelected;
%                         plot_title(d,time,0,0);

            %             plot_scale_bar(d)

        end

        % Button pushed function: PlotallButton
        function PlotallButtonPushed(app, event)

            colorspec = {'r','b','g','c','m','k'};

            initialTime = find(strcmp(app.StartDropDown.Items, app.StartDropDown.Value));
            endTime = find(strcmp(app.EndDropDown.Items, app.EndDropDown.Value));

            if app.VerticalhalfButton.Value
                [xq,yq] = meshgrid(-400:1:0,-400:1:0);
                middlePoint = size(yq,1);
            else
                [xq,yq] = meshgrid(-400:1:0,-400:1:400);
                middlePoint = round(size(yq,1)/2);
            end

            yPositions =  yq(:,1);
            xPositions = xq(1,:);

            if isa(app.analysisFigureHandle,'double') || (~isa(app.analysisFigureHandle,'double') && ~isvalid(app.analysisFigureHandle)) || app.NewplotCheckBox.Value
                app.analysisFigureHandle = figure(2);
                clf
                app.numberOfAnalysisPlots = 0;
                mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
                app.plotHandle = {};

                app.analysisFigureHandle.Position(1) = mainWindowPosition(1) + 600;
                app.analysisFigureHandle.Position(2) = mainWindowPosition(2);

                res = get(0,'screensize');

                position = get(gcf, 'Position');

                set(gcf, 'Position',  [position(1), position(2), 895, 895])

                position = get(gcf, 'Position');

                if sum(position([2 4])) + 100 > res(4)
                    position(2) = res(4) - position(4) - 100;
                end
                app.analysisFigureHandle.Position(2) = position(2);

            else
                app.analysisFigureHandle;
            end

            if ~app.ColorpickerCheckBox.Value
                if app.numberOfAnalysisPlots >= length(colorspec)
                    color = 'r';
                else
                    app.numberOfAnalysisPlots = app.numberOfAnalysisPlots + 1;
                    color = colorspec{app.numberOfAnalysisPlots};
                end
            else

                color = uisetcolor;
            end


            if ~app.HorizontalCheckBox.Value
                app.analysisFigureHandle.Position(3:4) = [400 800];
            else
                app.analysisFigureHandle.Position(3:4) = [800 800];
            end

            previousSelected = app.CallingApp.selectedFile;

            plotHandleTemp = {};
            
            for i = 1:length(app.SimulationListBox.Value)

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value{i})
                            fileIdx = ii;
                            break
                        end
                    end
                end

                app.CallingApp.selectedFile = fileIdx;

                d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
                d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
                d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;

                d.pl.substrateStyle = 1;
                d.pl.substrateForcesCentral = 0;
                d.pl.substrateForcesRepulsion = 0;
                d.pl.substrateForcesRestoration = 0;
                d.pl.substrateForcesFocalAdhesions = 0;
                d.pl.substrateForcesTotal = 0;
                d.pl.titleType = 0;
                d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
                d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


                d.simset.simulationType = 2;

                oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

                temp = import_settings([app.CallingApp.plotImport(fileIdx).folderName '/pointlike/pointlike_data.csv']);

                pointLikeCell = temp.cell;

                app.CallingApp.plotImport(fileIdx).currentTimePoint = initialTime;

                initialCells = import_cells(app.CallingApp,d,'post_plotting');

                app.CallingApp.plotImport(fileIdx).currentTimePoint = endTime;

                endCells = import_cells(app.CallingApp,d,'post_plotting');

                initialCellCenterX = zeros(1,length(initialCells));
                initialCellCenterY = zeros(1,length(initialCells));
                finalCellCenterX = zeros(1,length(endCells));
                finalCellCenterY = zeros(1,length(endCells));

                for k = 1:length(endCells)
                    initialCellCenterX(k) = mean(initialCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    initialCellCenterY(k) = mean(initialCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    finalCellCenterX(k) = mean(endCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    finalCellCenterY(k) = mean(endCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                end

                displacements = sqrt((initialCellCenterX - finalCellCenterX).^2 + (initialCellCenterY - finalCellCenterY).^2);

                moveX = -initialCellCenterX(pointLikeCell);
                moveY = -initialCellCenterY(pointLikeCell);

                initialCellCenterX = initialCellCenterX + moveX;
                initialCellCenterY = initialCellCenterY + moveY;

                dispData = griddata(initialCellCenterX,initialCellCenterY,displacements,xq,yq,'cubic');

                cellVertical = dispData(:,end);
                if app.HorizontalCheckBox.Value
                    cellHorizontal = dispData(middlePoint,1:end);
                end

                if exist([app.CallingApp.plotImport(fileIdx).folderName '/substrate'],'dir') == 7

                    importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(initialTime) '.csv']);
                    initialPointsX = importedPoints(:,1);
                    initialPointsY = importedPoints(:,2);

                    importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(endTime) '.csv']);
                    endPointsX = importedPoints(:,1);
                    endPointsY = importedPoints(:,2);

                    initialCells = import_cells(app.CallingApp,d,'post_plotting',1);

                    moveX = -mean(initialCells(pointLikeCell).verticesX).*d.spar.scalingLength.*1e6;
                    moveY = -mean(initialCells(pointLikeCell).verticesY).*d.spar.scalingLength.*1e6;

                    displacements = sqrt((endPointsX - initialPointsX).^2 + (endPointsY - initialPointsY).^2)*d.spar.scalingLength/1e-6;

                    subX = initialPointsX*d.spar.scalingLength/1e-6 + moveX;
                    subY = initialPointsY*d.spar.scalingLength/1e-6 + moveY;

                    dispData = griddata(subX,subY,displacements,xq,yq,'cubic');

                    subVertical = dispData(:,end);
                    if app.HorizontalCheckBox.Value
                        subHorizontal = dispData(middlePoint,1:end);
                    end

                else
                    app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                    app.CallingApp.selectedFile = previousSelected;
                    uialert(app.PointlikedisplacementandforcesUIFigure, ['Selected simulation number ' num2str(i) ' does not have substrate data.'],'Data missing');
                    return
                end

                app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;

                if app.HorizontalCheckBox.Value

                    app.analysisFigureHandle;
                    subplot(2,2,1);
                    hold on
                    if i == 1 && ~strcmp(app.LegendEditField.Value,'')
                        plotHandleTemp{end+1} = plot(yPositions(1:5:end),cellVertical(1:5:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField.Value); %#ok<*AGROW> 
                        legend('show', 'Interpreter', 'none');
                        app.LegendEditField.Value = '';
                    else
                        plotHandleTemp{end+1}.CellVertical = plot(yPositions(1:5:end),cellVertical(1:5:end),'Color', color,'LineWidth',1,'HandleVisibility','off');
                    end
                    hold off

                    title('Cell vertical diplacement', 'FontSize', 14)
                    set_disp_plot_axis(app.VerticalhalfButton.Value,app.LogYaxisCheckBox.Value,app.WindowsizeEditField.Value/2,min(min(cellHorizontal(:),min(cellVertical(:))))*0.5,app.MaximumdisplacementEditField.Value);
                    xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                    ylabel('Displacement {\mu}m', 'FontSize', 12)


                    subplot(2,2,2);
                    hold on
                    plotHandleTemp{end+1} = plot(xPositions(1:5:end),cellHorizontal(1:5:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                    hold off

                    title('Cell horizontal diplacement', 'FontSize', 14)
                    set_disp_plot_axis(true,app.LogYaxisCheckBox.Value,app.WindowsizeEditField.Value/2,min(min(cellHorizontal(:),min(cellVertical(:))))*0.5,app.MaximumdisplacementEditField.Value);
                    xlabel('Distance sideways ({\mu}m)', 'FontSize', 12)
                    ylabel('Displacement {\mu}m', 'FontSize', 12)


                    subplot(2,2,3);
                    hold on
                    plotHandleTemp{end+1} = plot(yPositions(1:5:end),subVertical(1:5:end),'Color',color,'LineWidth',1,'HandleVisibility','off');

                    hold off

                    title('Substrate vertical diplacement', 'FontSize', 14)
                    set_disp_plot_axis(app.VerticalhalfButton.Value,app.LogYaxisCheckBox.Value,app.WindowsizeEditField.Value/2,min(min(subHorizontal(:),min(subVertical(:))))*0.5,app.MaximumdisplacementEditField.Value);
                    xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                    ylabel('Displacement in {\mu}m', 'FontSize', 12)

                    subplot(2,2,4);
                    hold on
                    plotHandleTemp{end+1} = plot(xPositions(1:5:end),subHorizontal(1:5:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                    hold off

                    title('Substrate horizontal diplacement', 'FontSize', 14)
                    set_disp_plot_axis(true,app.LogYaxisCheckBox.Value,app.WindowsizeEditField.Value/2,min(min(subHorizontal(:),min(subVertical(:))))*0.5,app.MaximumdisplacementEditField.Value);
                    xlabel('Distance sideways ({\mu}m)', 'FontSize', 12)
                    ylabel('Displacement in {\mu}m', 'FontSize', 12)

                else
                    app.analysisFigureHandle;
                    subplot(2,1,1);
                    hold on
                    if i == 1 && ~strcmp(app.LegendEditField.Value,'')
                        plotHandleTemp{end+1} = plot(yPositions(1:5:end),cellVertical(1:5:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField.Value);
                        legend('show', 'Interpreter', 'none');
                        app.LegendEditField.Value = '';
                    else
                        plotHandleTemp{end+1} = plot(yPositions(1:5:end),cellVertical(1:5:end),'Color', color,'LineWidth',1,'HandleVisibility','off');
                    end
                    hold off

                    title('Cell vertical diplacement', 'FontSize', 14)
                    set_disp_plot_axis(app.VerticalhalfButton.Value,app.LogYaxisCheckBox.Value,app.WindowsizeEditField.Value/2,min(cellVertical(:))*0.5,app.MaximumdisplacementEditField.Value);
                    xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                    ylabel('Displacement {\mu}m', 'FontSize', 12)


                    subplot(2,1,2);
                    hold on
                    plotHandleTemp{end+1} = plot(yPositions(1:5:end),subVertical(1:5:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                    hold off

                    title('Substrate vertical diplacement', 'FontSize', 14)
                    set_disp_plot_axis(true,app.LogYaxisCheckBox.Value,app.WindowsizeEditField.Value/2,min(cellVertical(:))*0.5,app.MaximumdisplacementEditField.Value);
                    xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                    ylabel('Displacement in {\mu}m', 'FontSize', 12)
                end
            end
            app.plotHandle{end+1} = plotHandleTemp;
            app.CallingApp.selectedFile = previousSelected;

        end

        % Button pushed function: PlotdistributionButton
        function PlotdistributionButtonPushed(app, event)
            colorspec = {'r','b','g','c','m','k'};

            initialTime = find(strcmp(app.StartDropDown.Items, app.StartDropDown.Value));
            endTime = find(strcmp(app.EndDropDown.Items, app.EndDropDown.Value));

            if app.VerticalhalfButton.Value
                [xq,yq] = meshgrid(-400:1:0,-400:1:0);
                middlePoint = size(yq,1);
            else
                [xq,yq] = meshgrid(-400:1:0,-400:1:400);
                middlePoint = round(size(yq,1)/2);
            end

            yPositions =  yq(:,1);
            xPositions = xq(1,:);

            if isa(app.analysisFigureHandle,'double') || (~isa(app.analysisFigureHandle,'double') && ~isvalid(app.analysisFigureHandle)) || app.NewplotCheckBox.Value
                app.analysisFigureHandle = figure(2);
                clf
                app.numberOfAnalysisPlots = 0;
                mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

                app.plotHandle = {};

                app.analysisFigureHandle.Position(1) = mainWindowPosition(1) + 600;
                app.analysisFigureHandle.Position(2) = mainWindowPosition(2);

                res = get(0,'screensize');

                position = get(gcf, 'Position');

                set(gcf, 'Position',  [position(1), position(2), 895, 895])

                position = get(gcf, 'Position');

                if sum(position([2 4])) + 100 > res(4)
                    position(2) = res(4) - position(4) - 100;
                end
                app.analysisFigureHandle.Position(2) = position(2);

            else
                app.analysisFigureHandle;
            end

            if ~app.ColorpickerCheckBox.Value
                if app.numberOfAnalysisPlots >= length(colorspec)
                    color = 'r';
                else
                    app.numberOfAnalysisPlots = app.numberOfAnalysisPlots + 1;
                    color = colorspec{app.numberOfAnalysisPlots};
                end
            else

                color = uisetcolor;
            end


            if ~app.HorizontalCheckBox.Value
                app.analysisFigureHandle.Position(3:4) = [400 800];
            else
                app.analysisFigureHandle.Position(3:4) = [800 800];
            end

            cellVertical = zeros(size(yq,1),length(app.SimulationListBox.Value));
            subVertical = cellVertical;

            if app.HorizontalCheckBox.Value
                cellHorizontal = zeros(size(xq,2),length(app.SimulationListBox.Value));
                subHorizontal = cellHorizontal;
            end

            previousSelected = app.CallingApp.selectedFile;

            for i = 1:length(app.SimulationListBox.Value)

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value{i})
                            fileIdx = ii;
                            break
                        end
                    end
                end

                app.CallingApp.selectedFile = fileIdx;

                d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
                d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
                d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;

                d.pl.substrateStyle = 1;
                d.pl.substrateForcesCentral = 0;
                d.pl.substrateForcesRepulsion = 0;
                d.pl.substrateForcesRestoration = 0;
                d.pl.substrateForcesFocalAdhesions = 0;
                d.pl.substrateForcesTotal = 0;
                d.pl.titleType = 0;
                d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
                d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


                d.simset.simulationType = 2;

                oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

                temp = import_settings([app.CallingApp.plotImport(fileIdx).folderName '/pointlike/pointlike_data.csv']);

                pointLikeCell = temp.cell;

                app.CallingApp.plotImport(fileIdx).currentTimePoint = initialTime;

                initialCells = import_cells(app.CallingApp,d,'post_plotting');

                app.CallingApp.plotImport(fileIdx).currentTimePoint = endTime;

                endCells = import_cells(app.CallingApp,d,'post_plotting');

                initialCellCenterX = zeros(1,length(initialCells));
                initialCellCenterY = zeros(1,length(initialCells));
                finalCellCenterX = zeros(1,length(endCells));
                finalCellCenterY = zeros(1,length(endCells));

                for k = 1:length(endCells)
                    initialCellCenterX(k) = mean(initialCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    initialCellCenterY(k) = mean(initialCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    finalCellCenterX(k) = mean(endCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    finalCellCenterY(k) = mean(endCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                end

                displacements = sqrt((initialCellCenterX - finalCellCenterX).^2 + (initialCellCenterY - finalCellCenterY).^2);

                moveX = -initialCellCenterX(pointLikeCell);
                moveY = -initialCellCenterY(pointLikeCell);

                initialCellCenterX = initialCellCenterX + moveX;
                initialCellCenterY = initialCellCenterY + moveY;

                dispData = griddata(initialCellCenterX,initialCellCenterY,displacements,xq,yq,'cubic');

                cellVertical(:,i) = dispData(:,end);
                if app.HorizontalCheckBox.Value
                    cellHorizontal(:,i) = dispData(middlePoint,1:end)';
                end


                if exist([app.CallingApp.plotImport(fileIdx).folderName '/substrate'],'dir') == 7

                    importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(initialTime) '.csv']);
                    initialPointsX = importedPoints(:,1);
                    initialPointsY = importedPoints(:,2);

                    importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(endTime) '.csv']);
                    endPointsX = importedPoints(:,1);
                    endPointsY = importedPoints(:,2);

                    initialCells = import_cells(app.CallingApp,d,'post_plotting',1);

                    moveX = -mean(initialCells(pointLikeCell).verticesX).*d.spar.scalingLength.*1e6;
                    moveY = -mean(initialCells(pointLikeCell).verticesY).*d.spar.scalingLength.*1e6;

                    displacements = sqrt((endPointsX - initialPointsX).^2 + (endPointsY - initialPointsY).^2)*d.spar.scalingLength/1e-6;

                    subX = initialPointsX*d.spar.scalingLength/1e-6 + moveX;
                    subY = initialPointsY*d.spar.scalingLength/1e-6 + moveY;

                    dispData = griddata(subX,subY,displacements,xq,yq,'cubic');

                    subVertical(:,i) = dispData(:,end);
                    if app.HorizontalCheckBox.Value
                        subHorizontal(:,i) = dispData(middlePoint,1:end)';
                    end


                else
                    app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                    app.CallingApp.selectedFile = previousSelected;
                    uialert(app.PointlikedisplacementandforcesUIFigure, ['Selected simulation number ' num2str(i) ' does not have substrate data.'],'Data missing');
                    return
                end
            end


            if size(cellVertical,1) > size(cellVertical,2)
                cellVertMean = mean(cellVertical,2);
            else
                cellVertMean = mean(cellVertical,1);
            end

            if size(subVertical,1) > size(subVertical,2)
                subVertMean = mean(subVertical,2);
            else
                subVertMean = mean(subVertical,1);
            end

            if size(cellVertical,1) > size(cellVertical,2)
                cellVertStd = std(cellVertical,0,2);
            else
                cellVertStd = std(cellVertical,0,1);
            end

            if size(subVertical,1) > size(subVertical,2)
                subVertStd = std(subVertical,0,2);
            else
                subVertStd = std(subVertical,0,1);
            end


            if app.HorizontalCheckBox.Value

                if size(cellHorizontal,1) > size(cellHorizontal,2)
                    cellHorizMean = mean(cellHorizontal,2);
                else
                    cellHorizMean = mean(cellHorizontal,1);
                end

                if size(subHorizontal,1) > size(subHorizontal,2)
                    subHorizMean = mean(subHorizontal,2);
                else
                    subHorizMean = mean(subHorizontal,1);
                end

                if size(cellHorizontal,1) > size(cellHorizontal,2)
                    cellHorizStd = std(cellHorizontal,0,2);
                else
                    cellHorizStd = std(cellHorizontal,0,1);
                end

                if size(subHorizontal,1) > size(subHorizontal,2)
                    subHorizStd = std(subHorizontal,0,2);
                else
                    subHorizStd = std(subHorizontal,0,1);
                end

            end

            app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;

            plotHandleTemp = {};

            if app.HorizontalCheckBox.Value

                app.analysisFigureHandle;
                subplot(2,2,1);
                hold on
                if ~strcmp(app.LegendEditField.Value,'')
                    plotHandleTemp{end+1} = errorbar(yPositions(1:1:end),cellVertMean(1:1:end),cellVertStd(1:1:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField.Value);
                    legend('show', 'Interpreter', 'none');
                    app.LegendEditField.Value = '';
                else
                    plotHandleTemp{end+1} = errorbar(yPositions(1:1:end),cellVertMean(1:1:end),cellVertStd(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                end

                hold off

                title('Cell vertical diplacement', 'FontSize', 14)
                set_disp_plot_axis(app.VerticalhalfButton.Value,app.LogYaxisCheckBox.Value,app.WindowsizeEditField.Value/2,min(min(cellHorizMean(:),min(cellVertMean(:))))*0.5,app.MaximumdisplacementEditField.Value);
                xlabel('Distance downwards ({\mu}m)', 'FontSize', 12); ylabel('Displacement {\mu}m', 'FontSize', 12)


                subplot(2,2,2);
                hold on
                plotHandleTemp{end+1} = errorbar(xPositions(1:1:end),cellHorizMean(1:1:end),cellHorizStd(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');

                hold off

                title('Cell horizontal diplacement', 'FontSize', 14)
                set_disp_plot_axis(true,app.LogYaxisCheckBox.Value,app.WindowsizeEditField.Value/2,min(min(cellHorizMean(:),min(cellVertMean(:))))*0.5,app.MaximumdisplacementEditField.Value);
                xlabel('Distance sideways ({\mu}m)', 'FontSize', 12); ylabel('Displacement {\mu}m', 'FontSize', 12)

                subplot(2,2,3);
                hold on
                plotHandleTemp{end+1} = errorbar(yPositions(1:1:end),subVertMean(1:1:end),subVertStd(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');

                hold off

                title('Substrate vertical diplacement', 'FontSize', 14)
                set_disp_plot_axis(app.VerticalhalfButton.Value,app.LogYaxisCheckBox.Value,app.WindowsizeEditField.Value/2,min(min(subHorizMean(:),min(subVertMean(:))))*0.5,app.MaximumdisplacementEditField.Value);
                xlabel('Distance downwards ({\mu}m)', 'FontSize', 12); ylabel('Displacement in {\mu}m', 'FontSize', 12)


                subplot(2,2,4);
                hold on
                plotHandleTemp{end+1} = errorbar(xPositions(1:1:end),subHorizMean(1:1:end),subHorizStd(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');

                hold off

                title('Substrate horizontal diplacement', 'FontSize', 14)
                set_disp_plot_axis(true,app.LogYaxisCheckBox.Value,app.WindowsizeEditField.Value/2,min(min(subHorizMean(:),min(subVertMean(:))))*0.5,app.MaximumdisplacementEditField.Value);
                xlabel('Distance sideways ({\mu}m)', 'FontSize', 12); ylabel('Displacement in {\mu}m', 'FontSize', 12)


            else
                app.analysisFigureHandle;
                subplot(2,1,1);
                hold on
                if ~strcmp(app.LegendEditField.Value,'')
                    plotHandleTemp{end+1} = errorbar(yPositions(1:1:end),cellVertMean(1:1:end),cellVertStd(1:1:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField.Value);
                    legend('show', 'Interpreter', 'none');
                    app.LegendEditField.Value = '';
                else
                    plotHandleTemp{end+1} = errorbar(yPositions(1:1:end),cellVertMean(1:1:end),cellVertStd(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                end

                hold off

                title('Cell vertical diplacement', 'FontSize', 14)
                set_disp_plot_axis(app.VerticalhalfButton.Value,app.LogYaxisCheckBox.Value,app.WindowsizeEditField.Value/2,min(cellVertMean(:))*0.5,app.MaximumdisplacementEditField.Value);
                xlabel('Distance downwards ({\mu}m)', 'FontSize', 12); ylabel('Displacement {\mu}m', 'FontSize', 12)

                subplot(2,1,2);
                hold on
                plotHandleTemp{end+1} = errorbar(yPositions(1:1:end),subVertMean(1:1:end),subVertStd(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');

                hold off

                title('Substrate vertical diplacement', 'FontSize', 14)
                set_disp_plot_axis(app.VerticalhalfButton.Value,app.LogYaxisCheckBox.Value,app.WindowsizeEditField.Value/2,min(subVertMean(:))*0.5,app.MaximumdisplacementEditField.Value);
                xlabel('Distance downwards ({\mu}m)', 'FontSize', 12); ylabel('Displacement in {\mu}m', 'FontSize', 12)
            end

            app.plotHandle{end+1} = plotHandleTemp;

            warning('on','MATLAB:Axes:NegativeLimitsInLogAxis');


            app.CallingApp.selectedFile = previousSelected;

        end

        % Value changed function: ContoursEditField
        function ContoursEditFieldValueChanged(app, event)
            value = app.ContoursEditField.Value;
            if strcmp(value,'')
                app.ContoursCheckBox.Enable = 'off';
                app.ContoursCheckBox.Value = 0;
            else
                app.ContoursCheckBox.Enable = 'on';
            end
        end

        % Button pushed function: AreaplotButton_2
        function AreaplotButton_2Pushed(app, event)

            pastOption = set_force_plotting_options(app,0);

            [xq,yq] = meshgrid(-400:1:400,-400:1:400);

            timePoint = find(strcmp(app.TimePointDropDown.Items, app.TimePointDropDown.Value));

            dispData = zeros(length(xq(:,1)),length(xq(1,:)),length(app.SimulationListBox.Value));

            previousSelected = app.CallingApp.selectedFile;

            for i = 1:length(app.SimulationListBox.Value)

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value{i})
                            fileIdx = ii;
                            break
                        end
                    end
                end

                app.CallingApp.selectedFile = fileIdx;

                d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
                d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
                d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;


                d.pl.substrateStyle = 1;
                d.pl.substrateForcesCentral = 0;
                d.pl.substrateForcesRepulsion = 0;
                d.pl.substrateForcesRestoration = 0;
                d.pl.substrateForcesFocalAdhesions = 0;
                d.pl.substrateForcesTotal = 0;
                d.pl.titleType = 0;
                d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
                d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;

                d.simset.simulationType = 2;

                oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

                app.CallingApp.plotImport(fileIdx).currentTimePoint = timePoint;     
                
                [dispData,~] = get_forces_for_plotting(app,d,dispData,-1,fileIdx,i,xq,yq);
                if dispData == -1
                    app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                    app.CallingApp.selectedFile = previousSelected;
                    uialert(app.PointlikedisplacementandforcesUIFigure, ['Selected simulation number ' num2str(i) ' does not have substrate data.'],'Data missing');
                    return
                end
            end
            
            app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;

            dispData = mean(dispData,3);

            d = setup_axis_area_plot(app,d);

            hold on
            contourf(d.pl.axesHandle,xq,yq,dispData,256,'LineColor','none');

            mymap = csvread('./settings/Misc/mymap.csv');
            colormap(mymap)
            colorbar
            caxis([0 max(max(dispData(:)),1e-5)]);
            hold off

            app.CallingApp.selectedFile = previousSelected;

            set_force_plotting_options(app,pastOption);


        end

        % Value changed function: StartDropDown
        function StartDropDownValueChanged(app, event)
            if app.StartDropDown.Value > app.EndDropDown.Value
                uialert(app.PointlikedisplacementandforcesUIFigure,'Start time must be smaller than end time.')
                app.StartDropDown.Value = event.PreviousValue;
            end

        end

        % Value changed function: EndDropDown
        function EndDropDownValueChanged(app, event)
            if app.StartDropDown.Value > app.EndDropDown.Value
                uialert(app.PointlikedisplacementandforcesUIFigure,'End time must be larger than start time.')
                app.EndDropDown.Value = event.PreviousValue;
            end
        end

        % Value changed function: WindowsizeEditField
        function WindowsizeEditFieldValueChanged(app, event)
            app.WindowsizeEditField_2.Value = app.WindowsizeEditField.Value;
        end

        % Value changed function: WindowsizeEditField_2
        function WindowsizeEditField_2ValueChanged(app, event)
            app.WindowsizeEditField.Value = app.WindowsizeEditField_2.Value;
        end

        % Button pushed function: PlotallButton_2
        function PlotallButton_2Pushed(app, event)

            colorspec = {'r','b','g','c','m','k'};

            pastOption = set_force_plotting_options(app,0);

            timePoint = find(strcmp(app.TimePointDropDown.Items, app.TimePointDropDown.Value));

            if app.VerticalhalfButton_2.Value
                [xq,yq] = meshgrid(-400:1:0,-400:1:0);
                middlePoint = size(yq,1);
            else
                [xq,yq] = meshgrid(-400:1:0,-400:1:400);
                middlePoint = round(size(yq,1)/2);
            end

            yPositions =  yq(:,1);
            xPositions = xq(1,:);

            if isa(app.analysisFigureHandle,'double') || (~isa(app.analysisFigureHandle,'double') && ~isvalid(app.analysisFigureHandle)) || app.NewplotCheckBox_2.Value
                app.analysisFigureHandle = figure(2);
                clf
                app.numberOfAnalysisPlots = 0;
                mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
                app.plotHandle = {};

                app.analysisFigureHandle.Position(1) = mainWindowPosition(1) + 600;
                app.analysisFigureHandle.Position(2) = mainWindowPosition(2);

                res = get(0,'screensize');

                position = get(gcf, 'Position');

                set(gcf, 'Position',  [position(1), position(2), 895, 895])

                position = get(gcf, 'Position');

                if sum(position([2 4])) + 100 > res(4)
                    position(2) = res(4) - position(4) - 100;
                end
                app.analysisFigureHandle.Position(2) = position(2);

            else
                app.analysisFigureHandle;
            end

            if ~app.ColorpickerCheckBox_2.Value
                if app.numberOfAnalysisPlots >= length(colorspec)
                    color = 'r';
                else
                    app.numberOfAnalysisPlots = app.numberOfAnalysisPlots + 1;
                    color = colorspec{app.numberOfAnalysisPlots};
                end
            else
                color = uisetcolor;
            end


            if ~app.HorizontalCheckBox_2.Value
                app.analysisFigureHandle.Position(3:4) = [400 400];
            else
                app.analysisFigureHandle.Position(3:4) = [800 400];
            end


            previousSelected = app.CallingApp.selectedFile;

            for i = 1:length(app.SimulationListBox.Value)

                vertical = zeros(size(yq,1),1);
                horizontal = zeros(size(xq,2),1);
                
                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    end
                end

                app.CallingApp.selectedFile = fileIdx;

                d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
                d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
                d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;

                d.pl.substrateStyle = 1;
                d.pl.substrateForcesCentral = 0;
                d.pl.substrateForcesRepulsion = 0;
                d.pl.substrateForcesRestoration = 0;
                d.pl.substrateForcesFocalAdhesions = 0;
                d.pl.substrateForcesTotal = 0;
                d.pl.titleType = 0;
                d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
                d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


                d.simset.simulationType = 2;

                oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

                app.CallingApp.plotImport(fileIdx).currentTimePoint = timePoint;

                [vertical,horizontal] = get_forces_for_plotting(app,d,vertical,horizontal,fileIdx,1,xq,yq,middlePoint);
                if vertical == -1
                    app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                    app.CallingApp.selectedFile = previousSelected;
                    uialert(app.PointlikedisplacementandforcesUIFigure, ['Selected simulation number ' num2str(i) ' does not have substrate data.'],'Data missing');
                    return
                end

                app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;

                plotHandleTemp = {};

                if app.HorizontalCheckBox_2.Value

                    app.analysisFigureHandle;
                    subplot(1,2,1);
                    hold on
                    if i == 1 && ~strcmp(app.LegendEditField_2.Value,'')
                        plotHandleTemp{end+1} = plot(yPositions(1:1:end),vertical(1:1:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField_2.Value);
                        legend('show', 'Interpreter', 'none');
                        app.LegendEditField_2.Value = '';
                    else
                        plotHandleTemp{end+1} = plot(yPositions(1:1:end),vertical(1:1:end),'Color', color,'LineWidth',1,'HandleVisibility','off');
                    end

                    hold off

                    title(['Vertical ' app.ForceDropDown.Value], 'FontSize', 14)

                    set_force_plot_axis(app,app.VerticalhalfButton_2.Value,app.LogYaxisCheckBox_2.Value,app.HorizontalCheckBox_2.Value,vertical,horizontal);

                    xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                    ylabel('Force magnitude', 'FontSize', 12)


                    subplot(1,2,2);
                    hold on
                    plotHandleTemp{end+1} = plot(xPositions(1:1:end),horizontal(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                    hold off

                    title(['Horizontal ' app.ForceDropDown.Value], 'FontSize', 14)

                    set_force_plot_axis(app,true,app.LogYaxisCheckBox_2.Value,app.HorizontalCheckBox_2.Value,vertical,horizontal);

                    xlabel('Distance sideways ({\mu}m)', 'FontSize', 12)
                    ylabel('Force magnitude', 'FontSize', 12)


                else
                    app.analysisFigureHandle;

                    hold on
                    if i == 1 && ~strcmp(app.LegendEditField_2.Value,'')
                        plotHandleTemp{end+1} = plot(yPositions(1:1:end),vertical(1:1:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField_2.Value);
                        legend('show', 'Interpreter', 'none');
                        app.LegendEditField_2.Value = '';
                    else
                        plotHandleTemp{end+1} = plot(yPositions(1:1:end),vertical(1:1:end),'Color', color,'LineWidth',1,'HandleVisibility','off');
                    end
                    hold off

                    title(['Vertical ' app.ForceDropDown.Value], 'FontSize', 14)

                    set_force_plot_axis(app,app.VerticalhalfButton_2.Value,app.LogYaxisCheckBox_2.Value,app.HorizontalCheckBox_2.Value,vertical);

                    xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                    ylabel('Force magnitude', 'FontSize', 12)

                end
            end

            app.plotHandle{end+1} = plotHandleTemp;

            app.CallingApp.selectedFile = previousSelected;

            set_force_plotting_options(app,pastOption);

        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            if strcmp(app.TypeDropDown.Value,'Individual')
                app.PlotallButton_2.Enable = 'off';
                app.PlotdistributionButton_2.Enable = 'off';
            else
                app.PlotallButton_2.Enable = 'on';
                app.PlotdistributionButton_2.Enable = 'on';
            end

        end

        % Button pushed function: PlotdistributionButton_2
        function PlotdistributionButton_2Pushed(app, event)
            colorspec = {'r','b','g','c','m','k'};

            pastOption = set_force_plotting_options(app,0);

            timePoint = find(strcmp(app.TimePointDropDown.Items, app.TimePointDropDown.Value));

            if app.VerticalhalfButton_2.Value
                [xq,yq] = meshgrid(-400:1:0,-400:1:0);
                middlePoint = size(yq,1);
            else
                [xq,yq] = meshgrid(-400:1:0,-400:1:400);
                middlePoint = round(size(yq,1)/2);
            end

            yPositions =  yq(:,1);
            xPositions = xq(1,:);

            if isa(app.analysisFigureHandle,'double') || (~isa(app.analysisFigureHandle,'double') && ~isvalid(app.analysisFigureHandle)) || app.NewplotCheckBox_2.Value
                app.analysisFigureHandle = figure(2);
                clf
                app.numberOfAnalysisPlots = 0;
                mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
                app.plotHandle = {};

                app.analysisFigureHandle.Position(1) = mainWindowPosition(1) + 600;
                app.analysisFigureHandle.Position(2) = mainWindowPosition(2);

                res = get(0,'screensize');

                position = get(gcf, 'Position');

                set(gcf, 'Position',  [position(1), position(2), 895, 895])

                position = get(gcf, 'Position');

                if sum(position([2 4])) + 100 > res(4)
                    position(2) = res(4) - position(4) - 100;
                end
                app.analysisFigureHandle.Position(2) = position(2);

            else
                app.analysisFigureHandle;
            end

            if ~app.ColorpickerCheckBox_2.Value
                if app.numberOfAnalysisPlots >= length(colorspec)
                    color = 'r';
                else
                    app.numberOfAnalysisPlots = app.numberOfAnalysisPlots + 1;
                    color = colorspec{app.numberOfAnalysisPlots};
                end
            else
                color = uisetcolor;
            end


            if ~app.HorizontalCheckBox_2.Value
                app.analysisFigureHandle.Position(3:4) = [400 400];
            else
                app.analysisFigureHandle.Position(3:4) = [800 400];
            end

            vertical = zeros(size(yq,1),length(app.SimulationListBox.Value));
            horizontal = zeros(size(xq,2),length(app.SimulationListBox.Value));

            previousSelected = app.CallingApp.selectedFile;

            for i = 1:length(app.SimulationListBox.Value)

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    end
                end

                app.CallingApp.selectedFile = fileIdx;

                d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
                d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
                d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;

                d.pl.substrateStyle = 1;
                d.pl.substrateForcesCentral = 0;
                d.pl.substrateForcesRepulsion = 0;
                d.pl.substrateForcesRestoration = 0;
                d.pl.substrateForcesFocalAdhesions = 0;
                d.pl.substrateForcesTotal = 0;
                d.pl.titleType = 0;
                d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
                d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


                d.simset.simulationType = 2;

                oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

                app.CallingApp.plotImport(fileIdx).currentTimePoint = timePoint;

                [vertical,horizontal] = get_forces_for_plotting(app,d,vertical,horizontal,fileIdx,i,xq,yq,middlePoint);
                if vertical == -1
                    app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                    app.CallingApp.selectedFile = previousSelected;
                    uialert(app.PointlikedisplacementandforcesUIFigure, ['Selected simulation number ' num2str(i) ' does not have substrate data.'],'Data missing');
                    return
                end
                
            end

            if size(vertical,1) > size(vertical,2)
                vertMean = mean(vertical,2);
            else
                vertMean = mean(vertical,1);
            end

            if size(vertical,1) > size(vertical,2)
                vertStd = std(vertical,0,2);
            else
                vertStd = std(vertical,0,1);
            end

            if app.HorizontalCheckBox_2.Value

                if size(horizontal,1) > size(horizontal,2)
                    horizMean = mean(horizontal,2);
                else
                    horizMean = mean(horizontal,1);
                end

                if size(horizontal,1) > size(horizontal,2)
                    horizStd = std(horizontal,0,2);
                else
                    horizStd = std(horizontal,0,1);
                end
            end


            app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;

            plotHandleTemp = {};

            if app.HorizontalCheckBox_2.Value

                app.analysisFigureHandle;
                subplot(1,2,1);
                hold on
                if ~strcmp(app.LegendEditField_2.Value,'')
                    plotHandleTemp{end+1} = errorbar(yPositions(1:1:end),vertMean(1:1:end),vertStd(1:1:end),'Color',color,'DisplayName',app.LegendEditField_2.Value);
                    legend('show', 'Interpreter', 'none');
                    app.LegendEditField_2.Value = '';
                else
                    plotHandleTemp{end+1} = errorbar(yPositions(1:1:end),vertMean(1:1:end),vertStd(1:1:end),'Color',color,'HandleVisibility','off');
                end
                app.LegendEditField_2.Value = '';
                hold off

                title(['Vertical ' app.ForceDropDown.Value], 'FontSize', 14)

                set_force_plot_axis(app,app.VerticalhalfButton_2.Value,app.LogYaxisCheckBox_2.Value,app.HorizontalCheckBox_2.Value,vertMean,horizMean);
                
                xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                ylabel('Force magnitude', 'FontSize', 12)


                subplot(1,2,2);
                hold on
                plotHandleTemp{end+1} = errorbar(xPositions(1:1:end),horizMean(1:1:end),horizStd(1:1:end),'Color',color,'HandleVisibility','off');
                app.LegendEditField_2.Value = '';
                hold off

                title(['Horizontal ' app.ForceDropDown.Value], 'FontSize', 14)
                
                set_force_plot_axis(app,true,app.LogYaxisCheckBox_2.Value,app.HorizontalCheckBox_2.Value,vertical,horizontal);

                xlabel('Distance sideways ({\mu}m)', 'FontSize', 12)
                ylabel('Force magnitude', 'FontSize', 12)

            else
                app.analysisFigureHandle;

                hold on
                if ~strcmp(app.LegendEditField_2.Value,'')
                    plotHandleTemp{end+1} = errorbar(yPositions(1:1:end),vertMean(1:1:end),vertStd(1:1:end),'Color',color,'DisplayName',app.LegendEditField_2.Value);
                    legend('show', 'Interpreter', 'none');
                    app.LegendEditField_2.Value = '';
                else
                    plotHandleTemp{end+1} = errorbar(yPositions(1:1:end),vertMean(1:1:end),vertStd(1:1:end),'Color',color,'HandleVisibility','off');
                end
                app.LegendEditField_2.Value = '';
                hold off

                title(['Vertical ' app.ForceDropDown.Value], 'FontSize', 14)

                set_force_plot_axis(app,app.VerticalhalfButton_2.Value,app.LogYaxisCheckBox_2.Value,app.HorizontalCheckBox_2.Value,vertMean);

                xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                ylabel('Force magnitude', 'FontSize', 12)

            end

            app.plotHandle{end+1} = plotHandleTemp;

            app.CallingApp.selectedFile = previousSelected;

            set_force_plotting_options(app,pastOption);

        end

        % Button pushed function: RemovelastplotButton
        function RemovelastplotButtonPushed(app, event)
            if ~isempty(app.plotHandle)
                for i = 1:length(app.plotHandle{end})
                    delete(app.plotHandle{end}{i});
                end
                app.plotHandle(end) = [];
            end

            if isempty(app.plotHandle)
                app.numberOfAnalysisPlots = 0;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            if ~(isa(app.analysisFigureHandle,'double') || (~isa(app.analysisFigureHandle,'double') && ~isvalid(app.analysisFigureHandle)))

              
                if ~strcmp(app.TabGroup.SelectedTab.Title,'Forces')

                    cellsData = [];
                    subData = [];
                    cellsData_2 = [];
                    subData_2 = [];
                    Names = {};

                    for i = 1:length(app.plotHandle)
                        
                        if isfield(app.plotHandle{1}{1},'YPositiveDelta')
                            hasSTD = 1;
                        else
                            hasSTD = 0;
                        end
                        
                        
                        if ~app.HorizontalCheckBox.Value
                            if hasSTD
                                cellsData = [cellsData app.plotHandle{i}{1}.YData' app.plotHandle{i}{1}.YPositiveDelta'];

                                subData = [subData app.plotHandle{i}{2}.YData' app.plotHandle{i}{2}.YPositiveDelta'];
                            else
                                cellsData = [cellsData app.plotHandle{i}{1}.YData' zeros(size(app.plotHandle{i}{1}.YData'))];

                                subData = [subData app.plotHandle{i}{2}.YData' zeros(size(app.plotHandle{i}{2}.YData'))];
                            end
                        else
                            if hasSTD
                                cellsData = [cellsData app.plotHandle{i}{1}.YData' app.plotHandle{i}{1}.YPositiveDelta'];
                                cellsData_2 = [cellsData_2 app.plotHandle{i}{2}.YData' app.plotHandle{i}{2}.YPositiveDelta'];
                                subData = [subData app.plotHandle{i}{3}.YData' app.plotHandle{i}{3}.YPositiveDelta'];
                                subData_2 = [subData_2 app.plotHandle{i}{4}.YData' app.plotHandle{i}{4}.YPositiveDelta'];
                            else
                                cellsData = [cellsData app.plotHandle{i}{1}.YData' zeros(size(app.plotHandle{i}{1}.YData'))];
                                cellsData_2 = [cellsData_2 app.plotHandle{i}{2}.YData' zeros(size(app.plotHandle{2}{1}.YData'))];
                                subData = [subData app.plotHandle{i}{3}.YData' zeros(size(app.plotHandle{i}{3}.YData'))];
                                subData_2 = [subData_2 app.plotHandle{i}{4}.YData' zeros(size(app.plotHandle{i}{4}.YData'))];                                
                            end
                        end
                        if isempty(app.plotHandle{i}{1}.DisplayName)
                            Names = [Names ['unknown' num2str(i)]];
                            Names = [Names [num2str(i) '+-']];
                        else
                            Names = [Names app.plotHandle{i}{1}.DisplayName];
                            Names = [Names [num2str(i) '+-']];
                        end

                    end

                    Posy = app.plotHandle{1}{1}.XData';

                    Names = ['Position' Names];

                    [file, path] = uiputfile('.csv','Save the data from the figure');
                    if file == 0
                        return
                    end
                    [~,name,ext] = fileparts(file);


                    if ~app.HorizontalCheckBox.Value
                        writetable(array2table([Posy cellsData],'VariableNames',Names),[path name '_cell' ext]);
                        writetable(array2table([Posy subData],'VariableNames',Names),[path name '_sub' ext]);
                        fID = fopen([path name '_cell' ext]);

                        A = fread(fID,inf,'*char').';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name '_cell' ext],'w');
                        fprintf(fID,A);
                        fclose(fID);

                        fID = fopen([path name '_sub' ext]);

                        A = fread(fID,inf,'*char').';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name '_sub' ext],'w');
                        fprintf(fID,A);
                        fclose(fID);
                    else
                        writetable(array2table([Posy cellsData],'VariableNames',Names),[path name '_cell_vertical' ext]);
                        writetable(array2table([Posy subData],'VariableNames',Names),[path name '_sub_vertical' ext]);
                        writetable(array2table([Posy cellsData_2],'VariableNames',Names),[path name '_cell_horizontal' ext]);
                        writetable(array2table([Posy subData_2],'VariableNames',Names),[path name '_sub_horizontal' ext]);

                        fID = fopen([path name '_cell_vertical' ext]);

                        A = fread(fID,inf,'*char').';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name '_cell_vertical' ext],'w');
                        fprintf(fID,A);
                        fclose(fID);

                        fID = fopen([path name '_sub_vertical' ext]);

                        A = fread(fID,inf,'*char').';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name '_sub_vertical' ext],'w');
                        fprintf(fID,A);
                        fclose(fID);

                        fID = fopen([path name '_cell_horizontal' ext]);

                        A = fread(fID,inf,'*char').';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name '_cell_horizontal' ext],'w');
                        fprintf(fID,A);
                        fclose(fID);

                        fID = fopen([path name '_sub_horizontal' ext]);

                        A = fread(fID,inf,'*char').';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name '_sub_horizontal' ext],'w');
                        fprintf(fID,A);
                        fclose(fID);
                    end

                else

                    data = [];
                    Names = {};

                    for i = 1:length(app.plotHandle)
                        
                        if isfield(app.plotHandle{1}{1},'YPositiveDelta')
                            data = [data app.plotHandle{i}{1}.YData' app.plotHandle{i}{1}.YPositiveDelta'];
                        else
                            data = [data app.plotHandle{i}{1}.YData' zeros(size(app.plotHandle{i}{1}.YData'))];
                        end

                        if isempty(app.plotHandle{i}{1}.DisplayName)
                            Names = [Names ['unknown' num2str(i)]];
                            Names = [Names [num2str(i) '+-']];
                        else
                            Names = [Names app.plotHandle{i}{1}.DisplayName];
                            Names = [Names [num2str(i) '+-']];
                        end

                    end

                    Posy = app.plotHandle{i}{1}.XData';

                    Names = ['Position' Names];

                    [file, path] = uiputfile('.csv','Save the data from the figure');
                    if file == 0
                        return
                    end
                    
                    [~,name,ext] = fileparts(file);

                    if ~app.HorizontalCheckBox.Value
                        writetable(array2table([Posy data],'VariableNames',Names),[path name ext]);
                        fID = fopen([path name ext]);

                        A = fread(fID,inf,'*char').';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name ext],'w');
                        fprintf(fID,A);
                        fclose(fID);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PointlikedisplacementandforcesUIFigure = uifigure('Visible', 'off');
            app.PointlikedisplacementandforcesUIFigure.Name = 'Pointlike displacement and forces';
            app.PointlikedisplacementandforcesUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton = uibutton(app.PointlikedisplacementandforcesUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.TabGroup = uitabgroup(app.PointlikedisplacementandforcesUIFigure);
            app.DisplacementTab = uitab(app.TabGroup);
            app.DisplacementTab.Title = 'Displacement';
            app.DplotButtonGroup_3 = uibuttongroup(app.DisplacementTab);
            app.DplotButtonGroup_3.Title = '1D plot';
            app.VerticalhalfButton = uiradiobutton(app.DplotButtonGroup_3);
            app.VerticalhalfButton.Text = 'Vertical half';
            app.VerticalhalfButton.Value = true;
            app.VerticalfullButton = uiradiobutton(app.DplotButtonGroup_3);
            app.VerticalfullButton.Text = 'Vertical full';
            app.HorizontalCheckBox = uicheckbox(app.DplotButtonGroup_3);
            app.HorizontalCheckBox.Text = 'Horizontal';
            app.PlotallButton = uibutton(app.DplotButtonGroup_3, 'push');
            app.PlotallButton.ButtonPushedFcn = createCallbackFcn(app, @PlotallButtonPushed, true);
            app.PlotallButton.Enable = 'off';
            app.PlotallButton.Text = 'Plot all';
            app.PlotdistributionButton = uibutton(app.DplotButtonGroup_3, 'push');
            app.PlotdistributionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdistributionButtonPushed, true);
            app.PlotdistributionButton.Enable = 'off';
            app.PlotdistributionButton.Text = 'Plot distribution';
            app.LogYaxisCheckBox = uicheckbox(app.DplotButtonGroup_3);
            app.LogYaxisCheckBox.Text = 'Log Y axis';
            app.NewplotCheckBox = uicheckbox(app.DplotButtonGroup_3);
            app.NewplotCheckBox.Text = 'New plot';
            app.LegendEditFieldLabel = uilabel(app.DplotButtonGroup_3);
            app.LegendEditFieldLabel.Text = 'Legend';
            app.LegendEditField = uieditfield(app.DplotButtonGroup_3, 'text');
            app.ColorpickerCheckBox = uicheckbox(app.DplotButtonGroup_3);
            app.ColorpickerCheckBox.Text = 'Colorpicker';
            app.StartDropDownLabel = uilabel(app.DisplacementTab);
            app.StartDropDownLabel.Text = 'Start';
            app.StartDropDown = uidropdown(app.DisplacementTab);
            app.StartDropDown.Items = {};
            app.StartDropDown.ValueChangedFcn = createCallbackFcn(app, @StartDropDownValueChanged, true);
            app.StartDropDown.Value = {};
            app.EndDropDownLabel = uilabel(app.DisplacementTab);
            app.EndDropDownLabel.Text = 'End';
            app.EndDropDown = uidropdown(app.DisplacementTab);
            app.EndDropDown.Items = {};
            app.EndDropDown.ValueChangedFcn = createCallbackFcn(app, @EndDropDownValueChanged, true);
            app.EndDropDown.Value = {};
            app.WindowsizeEditFieldLabel = uilabel(app.DisplacementTab);
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.DisplacementTab, 'numeric');
            app.WindowsizeEditField.Limits = [0 Inf];
            app.WindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowsizeEditFieldValueChanged, true);
            app.WindowsizeEditField.Value = 200;
            app.mLabel_2 = uilabel(app.DisplacementTab);
            app.mLabel_2.Text = 'µm';
            app.MaximumdisplacementEditFieldLabel = uilabel(app.DisplacementTab);
            app.MaximumdisplacementEditFieldLabel.Text = 'Max displacement';
            app.MaximumdisplacementEditField = uieditfield(app.DisplacementTab, 'numeric');
            app.MaximumdisplacementEditField.Value = 30;
            app.mLabel = uilabel(app.DisplacementTab);
            app.mLabel.Text = 'µm';
            app.Displacement1DTipsImage = uiimage(app.DisplacementTab);
            app.Displacement1DTipsImage.Tooltip = {''};
            app.DisplacementTipsImage = uiimage(app.DisplacementTab);
            app.DisplacementTipsImage.Tooltip = {''};
            app.AreaplotPanel = uipanel(app.DisplacementTab);
            app.AreaplotPanel.Title = 'Area plot';
            app.DisplacementTypeButtonGroup = uibuttongroup(app.AreaplotPanel);
            app.CellsButton = uiradiobutton(app.DisplacementTypeButtonGroup);
            app.CellsButton.Text = 'Cells';
            app.CellsButton.Value = true;
            app.SubstrateButton = uiradiobutton(app.DisplacementTypeButtonGroup);
            app.SubstrateButton.Text = 'Substrate';
            app.AreaplotButton = uibutton(app.AreaplotPanel, 'push');
            app.AreaplotButton.ButtonPushedFcn = createCallbackFcn(app, @AreaplotButtonPushed, true);
            app.AreaplotButton.Enable = 'off';
            app.AreaplotButton.Text = 'Area plot';
            app.ContoursCheckBox = uicheckbox(app.AreaplotPanel);
            app.ContoursCheckBox.Text = 'Contours';
            app.ContoursEditField = uieditfield(app.AreaplotPanel, 'text');
            app.ContoursEditField.ValueChangedFcn = createCallbackFcn(app, @ContoursEditFieldValueChanged, true);
            app.ContoursEditField.Value = '1 2 5 10 15';
            app.DisplacementareaTipsImage = uiimage(app.DisplacementTab);
            app.DisplacementareaTipsImage.Tooltip = {''};
            app.ForcesTab = uitab(app.TabGroup);
            app.ForcesTab.Title = 'Forces';
            app.DplotButtonGroup_2 = uibuttongroup(app.ForcesTab);
            app.DplotButtonGroup_2.Title = '1D plot';
            app.VerticalhalfButton_2 = uiradiobutton(app.DplotButtonGroup_2);
            app.VerticalhalfButton_2.Text = 'Vertical half';
            app.VerticalhalfButton_2.Value = true;
            app.VerticalfullButton_2 = uiradiobutton(app.DplotButtonGroup_2);
            app.VerticalfullButton_2.Text = 'Vertical full';
            app.HorizontalCheckBox_2 = uicheckbox(app.DplotButtonGroup_2);
            app.HorizontalCheckBox_2.Text = 'Horizontal';
            app.PlotallButton_2 = uibutton(app.DplotButtonGroup_2, 'push');
            app.PlotallButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotallButton_2Pushed, true);
            app.PlotallButton_2.Enable = 'off';
            app.PlotallButton_2.Text = 'Plot all';
            app.PlotdistributionButton_2 = uibutton(app.DplotButtonGroup_2, 'push');
            app.PlotdistributionButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotdistributionButton_2Pushed, true);
            app.PlotdistributionButton_2.Enable = 'off';
            app.PlotdistributionButton_2.Text = 'Plot distribution';
            app.LogYaxisCheckBox_2 = uicheckbox(app.DplotButtonGroup_2);
            app.LogYaxisCheckBox_2.Text = 'Log Y axis';
            app.NewplotCheckBox_2 = uicheckbox(app.DplotButtonGroup_2);
            app.NewplotCheckBox_2.Text = 'New plot';
            app.LegendEditField_2Label = uilabel(app.DplotButtonGroup_2);
            app.LegendEditField_2Label.Text = 'Legend';
            app.LegendEditField_2 = uieditfield(app.DplotButtonGroup_2, 'text');
            app.ColorpickerCheckBox_2 = uicheckbox(app.DplotButtonGroup_2);
            app.ColorpickerCheckBox_2.Text = 'Colorpicker';
            app.mLabel_3 = uilabel(app.ForcesTab);
            app.mLabel_3.Text = 'µm';
            app.TimePointDropDownLabel = uilabel(app.ForcesTab);
            app.TimePointDropDownLabel.Text = 'Time Point';
            app.TimePointDropDown = uidropdown(app.ForcesTab);
            app.TimePointDropDown.Items = {};
            app.TimePointDropDown.Value = {};
            app.WindowsizeEditField_2Label = uilabel(app.ForcesTab);
            app.WindowsizeEditField_2Label.Text = 'Window size';
            app.WindowsizeEditField_2 = uieditfield(app.ForcesTab, 'numeric');
            app.WindowsizeEditField_2.Limits = [0 Inf];
            app.WindowsizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @WindowsizeEditField_2ValueChanged, true);
            app.WindowsizeEditField_2.Value = 200;
            app.ForceDropDownLabel = uilabel(app.ForcesTab);
            app.ForceDropDownLabel.Text = 'Force';
            app.ForceDropDown = uidropdown(app.ForcesTab);
            app.ForceDropDown.Items = {};
            app.ForceDropDown.Value = {};
            app.TypeDropDownLabel = uilabel(app.ForcesTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.ForcesTab);
            app.TypeDropDown.Items = {'Average', 'Maximum', 'Minimum', 'Individual'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Average';
            app.ForcesTipsImage = uiimage(app.ForcesTab);
            app.ForcesTipsImage.Tooltip = {''};
            app.AreaplotPanel_2 = uipanel(app.ForcesTab);
            app.AreaplotPanel_2.Title = 'Area plot';
            app.AreaplotButton_2 = uibutton(app.AreaplotPanel_2, 'push');
            app.AreaplotButton_2.ButtonPushedFcn = createCallbackFcn(app, @AreaplotButton_2Pushed, true);
            app.AreaplotButton_2.Enable = 'off';
            app.AreaplotButton_2.Text = 'Area plot';
            app.ForcesareaTipsImage = uiimage(app.ForcesTab);
            app.ForcesareaTipsImage.Tooltip = {''};
            app.Forces1DTipsImage = uiimage(app.ForcesTab);
            app.Forces1DTipsImage.Tooltip = {''};
            app.SimulationListBox = uilistbox(app.PointlikedisplacementandforcesUIFigure);
            app.SimulationListBox.Items = {};
            app.SimulationListBox.Multiselect = 'on';
            app.SimulationListBox.ValueChangedFcn = createCallbackFcn(app, @SimulationListBoxValueChanged, true);
            app.SimulationListBox.Value = {};
            app.RemovelastplotButton = uibutton(app.PointlikedisplacementandforcesUIFigure, 'push');
            app.RemovelastplotButton.ButtonPushedFcn = createCallbackFcn(app, @RemovelastplotButtonPushed, true);
            app.RemovelastplotButton.Text = 'Remove last plot';
            app.SaveButton = uibutton(app.PointlikedisplacementandforcesUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = '';
            % Show the figure after all components are created
            app.PointlikedisplacementandforcesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diplacementAndForceAnalysisDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PointlikedisplacementandforcesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PointlikedisplacementandforcesUIFigure)
        end
    end
end