classdef removeCellsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RemovesinglecellsButton  matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        CloseButton              matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        VisualizeshapeButton     matlab.ui.control.Button
        ApplyshapeButton         matlab.ui.control.Button
        ButtonGroup              matlab.ui.container.ButtonGroup
        SquareButton             matlab.ui.control.RadioButton
        CircleButton             matlab.ui.control.RadioButton
        mLabel                   matlab.ui.control.Label
        SizeLabel                matlab.ui.control.Label
        ShapeSizeEditField       matlab.ui.control.NumericEditField
        SaveButton               matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
    end

    properties (Access = public)
        CallingApp % Description
        enabledObjects = []; % Description
        cellsToBeRemoved = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Visible = 'Off';
            app.CallingApp = mainapp;
            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

            app.UIFigure.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.UIFigure.Position(4)/2;
            
            
            app.UIFigure.Visible = 'On';
            
            app.cellsToBeRemoved = app.CallingApp.import.removedCells;
        end

        % Button pushed function: RemovesinglecellsButton
        function RemovesinglecellsButtonPushed(app, event)
            
            disable_enable_all_function(app,'Off');
            
            d.simset.simulationType = 0;
            d.spar = scale_parameters(app.CallingApp);
            d.spar.scalingTime = app.CallingApp.import.scaledParameters.scalingTime;
            d = setup_plotting_options(app.CallingApp,'remove_cells',d);
            d.pl.highlightedCells = app.cellsToBeRemoved;
            
            d.pl.removedCells = app.cellsToBeRemoved;
            
            d.cells = import_cells(app.CallingApp,'basic_plotting');
            if app.CallingApp.CentercellsCheckBox.Value
                d.cells = center_cells(d.cells);
            end
            
            plot_function(d, 1);
            
            while 1
                
                removedCell = [];
                
                try
                    coords = ginput(1);
                    for k = 1:length(d.cells)
                        if check_if_inside(d.cells(k).verticesX,d.cells(k).verticesY,coords(:,1),coords(:,2))
                            if ~(strcmp(app.CallingApp.simulationType,'pointlike') && app.CallingApp.pointlikeProperties.cell == k)
                                removedCell = k;
                            end
                            break
                        end
                    end
                catch
                    
                    break
                end
                
                if ~isempty(removedCell)
                    hold on
                    
                    if any(d.pl.removedCells == removedCell)
                        d.pl.removedCells(d.pl.removedCells == removedCell) = 0;
                        d.pl.highlightedCells(d.pl.highlightedCells == removedCell) = 0;
                        highlight_cell(d,removedCell,1);
                    else
                        d.pl.highlightedCells(end+1) = removedCell;
                        d.pl.removedCells(end+1) = removedCell;
                        highlight_cell(d,removedCell);
                    end
                    plot_cell_numbers(d,removedCell);
                    hold off
                end
            end
            
            app.cellsToBeRemoved = d.pl.removedCells;
            disable_enable_all_function(app,'On');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.import.removedCells = app.cellsToBeRemoved;
            disable_enable_all_function(app.CallingApp,'On');

            if sum(app.cellsToBeRemoved) > 0
                set_object_properties_function(app.CallingApp,{'FittedButton','SquareButton', 'SubstrateparametersButton'},'Enable',{'On'});
                set_object_properties_function(app.CallingApp,{'EditstiffnessButton'},'Enable',{'Off'});
                set_object_properties_function(app.CallingApp,{'ConstantButton','HeterogeneousButton','GradientButton','YoungsmodulusEditField', 'YoungsmodulusEditFieldLabel','kPaLabel'},'Enable',{'On'});
                app.CallingApp.FittedButton.Value = 1;
                app.CallingApp.UseimportedsubstratedataCheckBox.Value = 0;
                app.CallingApp.UseimportedsubstratedataCheckBox_2.Value = 0;
                app.CallingApp.UseimportedsubstratedataCheckBox.Enable = 'Off';
                app.CallingApp.UseimportedsubstratedataCheckBox_2.Enable = 'Off';
            else
                if strcmp(app.CallingApp.UseimportedsubstratedataCheckBox.Enable, 'Off')
                    app.CallingApp.UseimportedsubstratedataCheckBox.Enable = 'On';
                    app.CallingApp.UseimportedsubstratedataCheckBox_2.Enable = 'On';
                end
            end
            
            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
            
        end

        % Button pushed function: VisualizeshapeButton
        function VisualizeshapeButtonPushed(app, event)
            
            disable_enable_all_function(app,'Off');
            
            d.simset.simulationType = 0;
            d.spar = scale_parameters(app.CallingApp);
            d.spar.scalingTime = app.CallingApp.import.scaledParameters.scalingTime;
            
            d = setup_plotting_options(app.CallingApp,'remove_with_shape',d);
            
            switch app.ButtonGroup.SelectedObject.Text
                case 'Square'
                    d.pl.shape = 1;
                case 'Circle'
                    d.pl.shape = 2;
            end
            d.pl.shapeSize = app.ShapeSizeEditField.Value*1e-6/d.spar.scalingLength/2;
            
            d.cells = import_cells(app.CallingApp,'basic_plotting');
            
            if app.CallingApp.CentercellsCheckBox.Value
                d.cells = center_cells(d.cells);
            end
            
            d.pl.outsideShape = [];
            for k = 1:length(d.cells)
                if ~(strcmp(app.CallingApp.simulationType,'pointlike') && app.CallingApp.pointlikeProperties.cell == k)
                    cellCenterX = mean(d.cells(k).verticesX);
                    cellCenterY = mean(d.cells(k).verticesY);
                    if d.pl.shape == 1
                        if ~(cellCenterX > -d.pl.shapeSize && cellCenterX < d.pl.shapeSize && cellCenterY > -d.pl.shapeSize && cellCenterY < d.pl.shapeSize)
                            d.pl.outsideShape(end+1) = k;
                        end
                    elseif d.pl.shape == 2
                        if ~(sqrt(cellCenterX^2 + cellCenterY^2) <= d.pl.shapeSize)
                            d.pl.outsideShape(end+1) = k;
                        end
                    end
                end
            end
            
            maxPointX = zeros(1,length(d.cells));
            minPointX = maxPointX;
            maxPointY = maxPointX;
            minPointY = maxPointX;
            for k = 1:length(d.cells)
                maxPointX(k) = max(d.cells(k).verticesX);
                minPointX(k) = min(d.cells(k).verticesX);
                maxPointY(k) = max(d.cells(k).verticesY);
                minPointY(k) = min(d.cells(k).verticesY);
            end
            
            maxSize = max(max([abs([maxPointX; minPointX; maxPointY; minPointY])]));
            
            if maxSize > d.pl.shapeSize
                d.pl.windowSize = maxSize + d.spar.rCell;
            else
                d.pl.windowSize = d.pl.shapeSize + d.spar.rCell;
            end
            d.pl.highlightedCells = app.cellsToBeRemoved;
            plot_function(d,1);
            
            disable_enable_all_function(app,'On');
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            switch app.ButtonGroup.SelectedObject.Text
                case 'Circle'
                    app.SizeLabel.Text = 'Diameter';
                case 'Square'
                    app.SizeLabel.Text = 'Size';
            end
            
        end

        % Button pushed function: ApplyshapeButton
        function ApplyshapeButtonPushed(app, event)
            
            
            spar = scale_parameters(app.CallingApp);
            
            cells = import_cells(app.CallingApp,'basic_plotting');
            
            shapeSize = app.ShapeSizeEditField.Value*1e-6/spar.scalingLength/2;
            
            outsideShape = [];
            for k = 1:length(cells)
                if ~(strcmp(app.CallingApp.simulationType,'pointlike') && app.CallingApp.pointlikeProperties.cell == k)
                    cellCenterX = mean(cells(k).verticesX);
                    cellCenterY = mean(cells(k).verticesY);
                    if strcmp(app.ButtonGroup.SelectedObject.Text,'Square')
                        if ~(cellCenterX > -shapeSize && cellCenterX < shapeSize && cellCenterY > -shapeSize && cellCenterY < shapeSize)
                            outsideShape(end+1) = k;
                        end
                    elseif strcmp(app.ButtonGroup.SelectedObject.Text,'Circle')
                        if ~(sqrt(cellCenterX^2 + cellCenterY^2) <= shapeSize)
                            outsideShape(end+1) = k;
                        end
                    end
                end
            end
            
            app.cellsToBeRemoved = unique([app.cellsToBeRemoved outsideShape]);
            
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if numel(app.cellsToBeRemoved) > 0
                if app.CallingApp.UseimportedsubstratedataCheckBox.Enable == 'off'
                    app.CallingApp.UseimportedsubstratedataCheckBox.Enable = 'On';
                    app.CallingApp.UseimportedsubstratedataCheckBox_2.Enable = 'On';
                end
            end
            app.cellsToBeRemoved = [];
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % choose file
            [filename, filepath] = uigetfile('*.txt','Choose removed cells to load');
            if ~ischar(filename)
                return
            end
            figure(app.UIFigure)
            
            app.cellsToBeRemoved = csvread([filepath '/' filename]);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename,filepath] = uiputfile({'*.txt'},'Save the removed cells','removed_cells.txt');
            if ~ischar(filepath)
                return
            end
            figure(app.UIFigure)
            
            writematrix(app.cellsToBeRemoved,[filepath filename]);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.CallingApp.import.removedCells = app.cellsToBeRemoved;
            disable_enable_all_function(app.CallingApp,'On');

            if sum(app.cellsToBeRemoved) > 0
                set_object_properties_function(app.CallingApp,{'FittedButton','SquareButton', 'SubstrateparametersButton'},'Enable',{'On'});
                set_object_properties_function(app.CallingApp,{'EditstiffnessButton'},'Enable',{'Off'});
                set_object_properties_function(app.CallingApp,{'ConstantButton','HeterogeneousButton','GradientButton','YoungsmodulusEditField', 'YoungsmodulusEditFieldLabel','kPaLabel'},'Enable',{'On'});
                app.CallingApp.FittedButton.Value = 1;
                app.CallingApp.UseimportedsubstratedataCheckBox.Value = 0;
                app.CallingApp.UseimportedsubstratedataCheckBox_2.Value = 0;
                app.CallingApp.UseimportedsubstratedataCheckBox.Enable = 'Off';
                app.CallingApp.UseimportedsubstratedataCheckBox_2.Enable = 'Off';
            end
            
            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RemovesinglecellsButton = uibutton(app.UIFigure, 'push');
            app.RemovesinglecellsButton.ButtonPushedFcn = createCallbackFcn(app, @RemovesinglecellsButtonPushed, true);
            app.RemovesinglecellsButton.Text = 'Remove single cells';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Panel = uipanel(app.UIFigure);
            app.VisualizeshapeButton = uibutton(app.Panel, 'push');
            app.VisualizeshapeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeshapeButtonPushed, true);
            app.VisualizeshapeButton.Text = 'Visualize shape';
            app.ApplyshapeButton = uibutton(app.Panel, 'push');
            app.ApplyshapeButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyshapeButtonPushed, true);
            app.ApplyshapeButton.Text = 'Apply shape';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SquareButton = uiradiobutton(app.ButtonGroup);
            app.SquareButton.Text = 'Square';
            app.CircleButton = uiradiobutton(app.ButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CircleButton.Value = true;
            app.mLabel = uilabel(app.Panel);
            app.mLabel.Text = 'µm';
            app.SizeLabel = uilabel(app.Panel);
            app.SizeLabel.Text = 'Size';
            app.ShapeSizeEditField = uieditfield(app.Panel, 'numeric');
            app.ShapeSizeEditField.Value = 250;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = removeCellsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end