classdef scaleBarSettingsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        mLabel                        matlab.ui.control.Label
        ScalebarlengthEditField       matlab.ui.control.NumericEditField
        ScalebarlengthEditFieldLabel  matlab.ui.control.Label
        ButtonGroup                   matlab.ui.container.ButtonGroup
        ScalingbarButton              matlab.ui.control.RadioButton
        StaticbarButton               matlab.ui.control.RadioButton
        ScalebarCheckBox              matlab.ui.control.CheckBox
        UITable                       matlab.ui.control.Table
        LoadButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        
    end
    
    properties (Access = public)
        enabledObjects = []; % Description
        cellsToBeRemoved = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Visible = 'Off';
            app.CallingApp = mainapp;
            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

            app.UIFigure.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.UIFigure.Position(4)/2;
            if size(app.CallingApp.scaleBarSettings.barLenghtData,1) > 1
                app.UITable.Data = app.CallingApp.scaleBarSettings.barLenghtData;
            end
                      
            app.UIFigure.Visible = 'On';
            app.ScalebarCheckBox.Value = (app.CallingApp.ScalebarCheckBox.Value == 1 || app.CallingApp.ScalebarCheckBox_2.Value == 1);
            switch app.ScalebarCheckBox.Value
                case 1
                    if app.CallingApp.scaleBarSettings.type == 1
                        set_object_properties_function(app,{'ScalingbarButton','StaticbarButton','ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'On'});
                        app.StaticbarButton.Value = 1;
                    elseif app.CallingApp.scaleBarSettings.type == 2
                        set_object_properties_function(app,{'UITable','ScalingbarButton','StaticbarButton'},'Enable',{'On'});
                        app.ScalingbarButton.Value = 1;
                    end
                case 0
                    set_object_properties_function(app,{'UITable','ScalingbarButton','StaticbarButton','ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'Off'});
                    if app.CallingApp.scaleBarSettings.type == 1
                        app.StaticbarButton.Value = 1;
                    elseif app.CallingApp.scaleBarSettings.type == 2
                        app.ScalingbarButton.Value = 1;
                    end
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.StaticbarButton.Value
                app.CallingApp.scaleBarSettings.type = 1;
            else
                app.CallingApp.scaleBarSettings.type = 2;
                app.CallingApp.scaleBarSettings.barLenghtData = app.UITable.Data;
            end

            disable_enable_all_function(app.CallingApp,'On');

            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if app.StaticbarButton.Value
                app.CallingApp.scaleBarSettings.type = 1;
                app.CallingApp.scaleBarSettings.barLength = app.ScalebarlengthEditField.Value;
            else
                app.CallingApp.scaleBarSettings.type = 2;
                app.CallingApp.scaleBarSettings.barLenghtData = app.UITable.Data;
            end

            disable_enable_all_function(app.CallingApp,'On');

            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            data = app.UITable.Data;
            
            if indices(2) == 2
                if indices(1) > 1
                    if newData <= data(indices(1)-1,indices(2))
                        app.UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    elseif newData >= data(indices(1),indices(2)+1)
                        app.UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    else
                        app.UITable.Data(indices(1)-1,indices(2)+1) = newData;
                    end
                else
                    if newData >= data(indices(1),indices(2)+1)
                        app.UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    end
                end
            elseif indices(2) == 3
                if indices(1) < size(app.UITable.Data,1)
                    if newData <= data(indices(1),indices(2)-1)
                        app.UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    elseif newData >= data(indices(1)+1,indices(2))
                        app.UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    else
                        app.UITable.Data(indices(1)+1,indices(2)-1) = newData;
                    end
                else
                    if newData <= data(indices(1),indices(2)-1)
                        app.UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    end
                end
            end       
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged2(app, event)
            switch app.ButtonGroup.SelectedObject.Text
                case 'Static bar'
                    set_object_properties_function(app,{'UITable'},'Enable',{'Off'});
                    set_object_properties_function(app,{'ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'On'});
                case 'Scaling bar'
                    if size(app.UITable.Data,1) == 1
                        app.StaticbarButton.Value = 1;
                        return
                    end
                    set_object_properties_function(app,{'UITable'},'Enable',{'On'});
                    set_object_properties_function(app,{'ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'Off'});
            end
        end

        % Value changed function: ScalebarCheckBox
        function ScalebarCheckBoxValueChanged(app, event)
            switch app.ScalebarCheckBox.Value
                case 1
                    switch app.ButtonGroup.SelectedObject.Text
                        case 'Static bar'
                            set_object_properties_function(app,{'UITable'},'Enable',{'Off'});
                            set_object_properties_function(app,{'ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'On'});
                        case 'Scaling bar'
                            if size(app.UITable.Data,1) == 1
                                app.StaticbarButton.Value = 1;
                                return
                            end
                            set_object_properties_function(app,{'UITable'},'Enable',{'On'});
                            set_object_properties_function(app,{'ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'Off'});
                    end
                    set_object_properties_function(app,{'ScalingbarButton','StaticbarButton'},'Enable',{'On'});
                    app.CallingApp.ScalebarCheckBox.Value = 1;
                    app.CallingApp.ScalebarCheckBox_2.Value = 1;
                case 0
                    set_object_properties_function(app,{'UITable','ScalingbarButton','StaticbarButton','ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'Off'});
                    app.CallingApp.ScalebarCheckBox.Value = 0;
                    app.CallingApp.ScalebarCheckBox_2.Value = 0;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Bar length'; 'Low limit'; 'High limit'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Enable = 'off';
            app.ScalebarCheckBox = uicheckbox(app.UIFigure);
            app.ScalebarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScalebarCheckBoxValueChanged, true);
            app.ScalebarCheckBox.Text = 'Scale bar';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged2, true);
            app.StaticbarButton = uiradiobutton(app.ButtonGroup);
            app.StaticbarButton.Enable = 'off';
            app.StaticbarButton.Text = 'Static bar';
            app.StaticbarButton.Value = true;
            app.ScalingbarButton = uiradiobutton(app.ButtonGroup);
            app.ScalingbarButton.Enable = 'off';
            app.ScalingbarButton.Text = 'Scaling bar';
            app.ScalebarlengthEditFieldLabel = uilabel(app.UIFigure);
            app.ScalebarlengthEditFieldLabel.Enable = 'off';
            app.ScalebarlengthEditFieldLabel.Text = 'Scale bar length';
            app.ScalebarlengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScalebarlengthEditField.Enable = 'off';
            app.ScalebarlengthEditField.Value = 30;
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Enable = 'off';
            app.mLabel.Text = 'µm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scaleBarSettingsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end